classdef User_Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DModelCreationbyDanielBriguetUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeneralSettingsTab              matlab.ui.container.Tab
        SequFolderLocationEditField_3Label  matlab.ui.control.Label
        SequFolderLocationEditField     matlab.ui.control.EditField
        ImageFormatEditField_3Label     matlab.ui.control.Label
        ImageFormatEditField            matlab.ui.control.EditField
        STLFileNameEditField_3Label     matlab.ui.control.Label
        STLFileNameEditField            matlab.ui.control.EditField
        ScaleEditFieldLabel             matlab.ui.control.Label
        STLFileScaleEditField           matlab.ui.control.NumericEditField
        ImagesInformationLabel          matlab.ui.control.Label
        ResultLabel                     matlab.ui.control.Label
        FileCompressionEditFieldLabel   matlab.ui.control.Label
        FileCompressionEditField        matlab.ui.control.NumericEditField
        ReconstructionScriptLabel       matlab.ui.control.Label
        RandomizedValuesEditFieldLabel  matlab.ui.control.Label
        RandomizedValuesEditField       matlab.ui.control.NumericEditField
        EnableErrorCreationCheckBox     matlab.ui.control.CheckBox
        GroundAngleEditFieldLabel       matlab.ui.control.Label
        GroundAngleEditField            matlab.ui.control.NumericEditField
        CalibrationTab                  matlab.ui.container.Tab
        CalibFolderLocationEditFieldLabel  matlab.ui.control.Label
        CalibFolderLocationEditField    matlab.ui.control.EditField
        CalibrationMethodeButtonGroup   matlab.ui.container.ButtonGroup
        CameraCharacteristicsButton     matlab.ui.control.RadioButton
        CheckerboardButton              matlab.ui.control.RadioButton
        SquaresizemmEditFieldLabel      matlab.ui.control.Label
        SquaresizemmEditField           matlab.ui.control.NumericEditField
        CheckerboardLabel               matlab.ui.control.Label
        CameraCharacteristicsLabel      matlab.ui.control.Label
        PixelsizeumEditFieldLabel       matlab.ui.control.Label
        PixelsizeumEditField            matlab.ui.control.NumericEditField
        ImagersizemmEditFieldLabel      matlab.ui.control.Label
        ImagersizemmEditField           matlab.ui.control.NumericEditField
        ActivePixelsEditFieldLabel      matlab.ui.control.Label
        ActivePixelsEditField           matlab.ui.control.NumericEditField
        FPSEditFieldLabel               matlab.ui.control.Label
        FPSEditField                    matlab.ui.control.NumericEditField
        ProcessingTab                   matlab.ui.container.Tab
        ContrastEditFieldLabel          matlab.ui.control.Label
        ContrastEditField               matlab.ui.control.NumericEditField
        RotationEditFieldLabel          matlab.ui.control.Label
        RotationEditField               matlab.ui.control.NumericEditField
        FinderMinObjectSizeEditFieldLabel  matlab.ui.control.Label
        FinderMinObjectSizeEditField    matlab.ui.control.NumericEditField
        MirrorYAxeCheckBox              matlab.ui.control.CheckBox
        ErrorfillingmethodDropDownLabel  matlab.ui.control.Label
        ErrorfillingmethodDropDown      matlab.ui.control.DropDown
        ImageProcessingLabel            matlab.ui.control.Label
        MatrixProcessingLabel           matlab.ui.control.Label
        LimiterLabel                    matlab.ui.control.Label
        PonderationEditFieldLabel       matlab.ui.control.Label
        PonderationEditField            matlab.ui.control.NumericEditField
        AreaEditFieldLabel              matlab.ui.control.Label
        AreaEditField                   matlab.ui.control.NumericEditField
        SmoothfactorSpinnerLabel        matlab.ui.control.Label
        SmoothfactorSpinner             matlab.ui.control.Spinner
        HeightLabel                     matlab.ui.control.Label
        MinimalobjectsizeEditFieldLabel  matlab.ui.control.Label
        MinimalobjectsizeEditField      matlab.ui.control.NumericEditField
        ObjectscheckedEditFieldLabel    matlab.ui.control.Label
        ObjectscheckedEditField         matlab.ui.control.NumericEditField
        SetupParametersTab              matlab.ui.container.Tab
        MotorLabel                      matlab.ui.control.Label
        AlimentationVEditFieldLabel     matlab.ui.control.Label
        AlimentationVEditField          matlab.ui.control.NumericEditField
        AxisDiameterEditFieldLabel      matlab.ui.control.Label
        AxisDiameterEditField           matlab.ui.control.NumericEditField
        Polynom1EditFieldLabel          matlab.ui.control.Label
        Polynom1EditField               matlab.ui.control.NumericEditField
        Polynom2EditFieldLabel          matlab.ui.control.Label
        Polynom2EditField               matlab.ui.control.NumericEditField
        Polynom3EditFieldLabel          matlab.ui.control.Label
        Polynom3EditField               matlab.ui.control.NumericEditField
        MotorFunctionLabel              matlab.ui.control.Label
        AngleEditFieldLabel             matlab.ui.control.Label
        AngleEditField                  matlab.ui.control.NumericEditField
        LaserLabel                      matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        wereonlygroundvisibleatsequencestartLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        NbrofimagesforcheckEditFieldLabel  matlab.ui.control.Label
        NbrofimagesforcheckEditField    matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % General settings
            FolderNameSequenze = app.SequFolderLocationEditField.Value;
            ImageFormat = app.ImageFormatEditField.Value;
            NameSTLFile = app.STLFileNameEditField.Value;
            FileCompression = app.FileCompressionEditField.Value;
            FileScale = app.STLFileScaleEditField.Value;
            
            % Calibration settings
            FolderNameCalibration = app.CalibFolderLocationEditField.Value;
            if(app.CameraCharacteristicsButton.Value)
                EnableCheckerboard = 0;
            else
                EnableCheckerboard = 1;
            end
            
            SquareSize = app.SquaresizemmEditField.Value;
            PixelSize = app.PixelsizeumEditField.Value;
            ImagerSize = app.ImagersizemmEditField.Value;
            ActivePixels = app.ActivePixelsEditField.Value;
            
            % Processing settings
            ImageContrast = app.ContrastEditField.Value;
            ImageRotation = app.RotationEditField.Value;
            FinderMinSize = app.FinderMinObjectSizeEditField.Value;
            MirrorYAxe = app.MirrorYAxeCheckBox.Value;
            FillingMethod = app.ErrorfillingmethodDropDown.Value;
            SmoothFactor = app.SmoothfactorSpinner.Value;
            LimiterPonderation = app.PonderationEditField.Value;
            LimiterArea = app.AreaEditField.Value;
            
            % Setup settings
            MotorAlimentation = app.AlimentationVEditField.Value;
            MotorAxisDiameter = app.AxisDiameterEditField.Value;
            MotorSpeedPolynom = [app.Polynom3EditField.Value app.Polynom2EditField.Value app.Polynom1EditField.Value ];
            LaserAngle = app.AngleEditField.Value;
            CameraFPS = app.FPSEditField.Value;
            
            % Correction settings
            ObjectsChecked = app.ObjectscheckedEditField.Value;
            ImagesChecked = app.NbrofimagesforcheckEditField.Value;
            ObjectSize = app.MinimalobjectsizeEditField.Value;
            
            % Error settings
            ErrorEnabled = app.EnableErrorCreationCheckBox.Value;
            ErrorPercentage = app.RandomizedValuesEditField.Value;
            GroundAngleError = app.GroundAngleEditField.Value;


            reconstruction(FolderNameSequenze, ImageFormat, NameSTLFile, ...
                        EnableCheckerboard, FolderNameCalibration, ...
                        SquareSize, PixelSize, ImagerSize, ...
                        ActivePixels, ImageContrast, ImageRotation, ...
                        FinderMinSize, MirrorYAxe, FillingMethod, ...
                        SmoothFactor, LimiterPonderation, LimiterArea, ...
                        MotorAlimentation, MotorAxisDiameter, ...
                        MotorSpeedPolynom, LaserAngle, CameraFPS, ...
                        FileCompression, FileScale, ObjectsChecked, ...
                        ImagesChecked, ObjectSize, ErrorEnabled, ...
                        ErrorPercentage, GroundAngleError);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DModelCreationbyDanielBriguetUIFigure = uifigure;
            app.DModelCreationbyDanielBriguetUIFigure.Name = '3D Model Creation by Daniel Briguet';
            setAutoResize(app, app.DModelCreationbyDanielBriguetUIFigure, true)
            app.TabGroup = uitabgroup(app.DModelCreationbyDanielBriguetUIFigure);
            app.GeneralSettingsTab = uitab(app.TabGroup);
            app.GeneralSettingsTab.Title = 'General Settings';
            app.GeneralSettingsTab.Units = 'pixels';
            app.SequFolderLocationEditField_3Label = uilabel(app.GeneralSettingsTab);
            app.SequFolderLocationEditField_3Label.Text = 'Sequ. Folder Location';
            app.SequFolderLocationEditField = uieditfield(app.GeneralSettingsTab, 'text');
            app.SequFolderLocationEditField.Value = '\sequenz2';
            app.ImageFormatEditField_3Label = uilabel(app.GeneralSettingsTab);
            app.ImageFormatEditField_3Label.Text = 'Image Format';
            app.ImageFormatEditField = uieditfield(app.GeneralSettingsTab, 'text');
            app.ImageFormatEditField.Value = 'jpg';
            app.STLFileNameEditField_3Label = uilabel(app.GeneralSettingsTab);
            app.STLFileNameEditField_3Label.Text = 'STL File Name';
            app.STLFileNameEditField = uieditfield(app.GeneralSettingsTab, 'text');
            app.STLFileNameEditField.Value = 'model';
            app.ScaleEditFieldLabel = uilabel(app.GeneralSettingsTab);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.STLFileScaleEditField = uieditfield(app.GeneralSettingsTab, 'numeric');
            app.STLFileScaleEditField.Limits = [0 Inf];
            app.STLFileScaleEditField.Value = 1;
            app.ImagesInformationLabel = uilabel(app.GeneralSettingsTab);
            app.ImagesInformationLabel.Text = 'Images Information';
            app.ResultLabel = uilabel(app.GeneralSettingsTab);
            app.ResultLabel.Text = 'Result';
            app.FileCompressionEditFieldLabel = uilabel(app.GeneralSettingsTab);
            app.FileCompressionEditFieldLabel.Text = 'File Compression';
            app.FileCompressionEditField = uieditfield(app.GeneralSettingsTab, 'numeric');
            app.FileCompressionEditField.Limits = [0 1];
            app.FileCompressionEditField.Value = 1;
            app.ReconstructionScriptLabel = uilabel(app.GeneralSettingsTab);
            app.ReconstructionScriptLabel.Text = 'Reconstruction Script';
            app.RandomizedValuesEditFieldLabel = uilabel(app.GeneralSettingsTab);
            app.RandomizedValuesEditFieldLabel.Text = 'Randomized Values [%]';
            app.RandomizedValuesEditField = uieditfield(app.GeneralSettingsTab, 'numeric');
            app.RandomizedValuesEditField.Limits = [1 100];
            app.RandomizedValuesEditField.Value = 15;
            app.EnableErrorCreationCheckBox = uicheckbox(app.GeneralSettingsTab);
            app.EnableErrorCreationCheckBox.Text = 'Enable Error Creation';
            app.GroundAngleEditFieldLabel = uilabel(app.GeneralSettingsTab);
            app.GroundAngleEditFieldLabel.Text = 'Ground Angle';
            app.GroundAngleEditField = uieditfield(app.GeneralSettingsTab, 'numeric');
            app.GroundAngleEditField.Limits = [1 Inf];
            app.GroundAngleEditField.Value = 1.5;
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.CalibrationTab.Units = 'pixels';
            app.CalibFolderLocationEditFieldLabel = uilabel(app.CalibrationTab);
            app.CalibFolderLocationEditFieldLabel.Text = 'Calib. Folder Location';
            app.CalibFolderLocationEditField = uieditfield(app.CalibrationTab, 'text');
            app.CalibFolderLocationEditField.Value = '\calibration_pic';
            app.CalibrationMethodeButtonGroup = uibuttongroup(app.CalibrationTab);
            app.CalibrationMethodeButtonGroup.Title = 'Calibration Methode';
            app.CameraCharacteristicsButton = uiradiobutton(app.CalibrationMethodeButtonGroup);
            app.CameraCharacteristicsButton.Text = 'Camera Characteristics';
            app.CameraCharacteristicsButton.Value = true;
            app.CheckerboardButton = uiradiobutton(app.CalibrationMethodeButtonGroup);
            app.CheckerboardButton.Text = 'Checkerboard';
            app.SquaresizemmEditFieldLabel = uilabel(app.CalibrationTab);
            app.SquaresizemmEditFieldLabel.Text = 'Square size [mm]';
            app.SquaresizemmEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.SquaresizemmEditField.Limits = [0 Inf];
            app.SquaresizemmEditField.Value = 0.029;
            app.CheckerboardLabel = uilabel(app.CalibrationTab);
            app.CheckerboardLabel.Text = 'Checkerboard';
            app.CameraCharacteristicsLabel = uilabel(app.CalibrationTab);
            app.CameraCharacteristicsLabel.Text = 'Camera Characteristics';
            app.PixelsizeumEditFieldLabel = uilabel(app.CalibrationTab);
            app.PixelsizeumEditFieldLabel.Text = 'Pixel size [um]';
            app.PixelsizeumEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.PixelsizeumEditField.Limits = [0 Inf];
            app.PixelsizeumEditField.Value = 2.2;
            app.ImagersizemmEditFieldLabel = uilabel(app.CalibrationTab);
            app.ImagersizemmEditFieldLabel.Text = 'Imager size [mm]';
            app.ImagersizemmEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.ImagersizemmEditField.Limits = [0 Inf];
            app.ImagersizemmEditField.Value = 5.7;
            app.ActivePixelsEditFieldLabel = uilabel(app.CalibrationTab);
            app.ActivePixelsEditFieldLabel.Text = 'Active Pixels';
            app.ActivePixelsEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.ActivePixelsEditField.Limits = [0 Inf];
            app.ActivePixelsEditField.Value = 2592;
            app.FPSEditFieldLabel = uilabel(app.CalibrationTab);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.FPSEditField.Limits = [0 Inf];
            app.FPSEditField.Value = 7;
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.ProcessingTab.Units = 'pixels';
            app.ContrastEditFieldLabel = uilabel(app.ProcessingTab);
            app.ContrastEditFieldLabel.Text = 'Contrast';
            app.ContrastEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.ContrastEditField.Limits = [0 1];
            app.ContrastEditField.Value = 0.9;
            app.RotationEditFieldLabel = uilabel(app.ProcessingTab);
            app.RotationEditFieldLabel.Text = 'Rotation';
            app.RotationEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.RotationEditField.Limits = [-3 3];
            app.FinderMinObjectSizeEditFieldLabel = uilabel(app.ProcessingTab);
            app.FinderMinObjectSizeEditFieldLabel.Text = 'Finder Min. Object Size';
            app.FinderMinObjectSizeEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.FinderMinObjectSizeEditField.Limits = [0 Inf];
            app.FinderMinObjectSizeEditField.Value = 10;
            app.MirrorYAxeCheckBox = uicheckbox(app.ProcessingTab);
            app.MirrorYAxeCheckBox.Text = 'Mirror Y Axe';
            app.MirrorYAxeCheckBox.Value = true;
            app.ErrorfillingmethodDropDownLabel = uilabel(app.ProcessingTab);
            app.ErrorfillingmethodDropDownLabel.Text = 'Error filling method';
            app.ErrorfillingmethodDropDown = uidropdown(app.ProcessingTab);
            app.ErrorfillingmethodDropDown.Items = {'nearest', 'constant', 'previous', 'next', 'linear', 'spline', 'pchip'};
            app.ErrorfillingmethodDropDown.Value = 'nearest';
            app.ImageProcessingLabel = uilabel(app.ProcessingTab);
            app.ImageProcessingLabel.Text = 'Image Processing';
            app.MatrixProcessingLabel = uilabel(app.ProcessingTab);
            app.MatrixProcessingLabel.Text = 'Matrix Processing';
            app.LimiterLabel = uilabel(app.ProcessingTab);
            app.LimiterLabel.Text = 'Limiter';
            app.PonderationEditFieldLabel = uilabel(app.ProcessingTab);
            app.PonderationEditFieldLabel.Text = 'Ponderation';
            app.PonderationEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.PonderationEditField.Limits = [0 Inf];
            app.PonderationEditField.Value = 0.015;
            app.AreaEditFieldLabel = uilabel(app.ProcessingTab);
            app.AreaEditFieldLabel.Text = 'Area';
            app.AreaEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.AreaEditField.Limits = [1 Inf];
            app.AreaEditField.Value = 20;
            app.SmoothfactorSpinnerLabel = uilabel(app.ProcessingTab);
            app.SmoothfactorSpinnerLabel.Text = 'Smooth factor';
            app.SmoothfactorSpinner = uispinner(app.ProcessingTab);
            app.SmoothfactorSpinner.Limits = [0 100];
            app.SmoothfactorSpinner.Value = 2;
            app.HeightLabel = uilabel(app.ProcessingTab);
            app.HeightLabel.Text = 'Height';
            app.MinimalobjectsizeEditFieldLabel = uilabel(app.ProcessingTab);
            app.MinimalobjectsizeEditFieldLabel.Text = 'Minimal object size';
            app.MinimalobjectsizeEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.MinimalobjectsizeEditField.Limits = [0 Inf];
            app.MinimalobjectsizeEditField.Value = 15;
            app.ObjectscheckedEditFieldLabel = uilabel(app.ProcessingTab);
            app.ObjectscheckedEditFieldLabel.Text = 'Objects checked ';
            app.ObjectscheckedEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.ObjectscheckedEditField.Limits = [1 50];
            app.ObjectscheckedEditField.Value = 20;
            app.SetupParametersTab = uitab(app.TabGroup);
            app.SetupParametersTab.Title = 'Setup Parameters';
            app.SetupParametersTab.Units = 'pixels';
            app.MotorLabel = uilabel(app.SetupParametersTab);
            app.MotorLabel.Text = 'Motor';
            app.AlimentationVEditFieldLabel = uilabel(app.SetupParametersTab);
            app.AlimentationVEditFieldLabel.Text = 'Alimentation [V]';
            app.AlimentationVEditField = uieditfield(app.SetupParametersTab, 'numeric');
            app.AlimentationVEditField.Limits = [0 Inf];
            app.AlimentationVEditField.Value = 2;
            app.AxisDiameterEditFieldLabel = uilabel(app.SetupParametersTab);
            app.AxisDiameterEditFieldLabel.Text = 'Axis Diameter';
            app.AxisDiameterEditField = uieditfield(app.SetupParametersTab, 'numeric');
            app.AxisDiameterEditField.Limits = [0 Inf];
            app.AxisDiameterEditField.Value = 6;
            app.Polynom1EditFieldLabel = uilabel(app.SetupParametersTab);
            app.Polynom1EditFieldLabel.Text = 'Polynom1';
            app.Polynom1EditField = uieditfield(app.SetupParametersTab, 'numeric');
            app.Polynom1EditField.Limits = [0 Inf];
            app.Polynom1EditField.Value = 0.2827;
            app.Polynom2EditFieldLabel = uilabel(app.SetupParametersTab);
            app.Polynom2EditFieldLabel.Text = 'Polynom2';
            app.Polynom2EditField = uieditfield(app.SetupParametersTab, 'numeric');
            app.Polynom2EditField.Limits = [0 Inf];
            app.Polynom2EditField.Value = 0.5978;
            app.Polynom3EditFieldLabel = uilabel(app.SetupParametersTab);
            app.Polynom3EditFieldLabel.Text = 'Polynom3';
            app.Polynom3EditField = uieditfield(app.SetupParametersTab, 'numeric');
            app.Polynom3EditField.Limits = [0 Inf];
            app.Polynom3EditField.Value = 0.0201;
            app.MotorFunctionLabel = uilabel(app.SetupParametersTab);
            app.MotorFunctionLabel.Text = 'Motor Function';
            app.AngleEditFieldLabel = uilabel(app.SetupParametersTab);
            app.AngleEditFieldLabel.Text = 'Angle [°]';
            app.AngleEditField = uieditfield(app.SetupParametersTab, 'numeric');
            app.AngleEditField.Limits = [0 90];
            app.AngleEditField.Value = 9;
            app.LaserLabel = uilabel(app.SetupParametersTab);
            app.LaserLabel.Text = 'Laser';
            app.StartButton = uibutton(app.SetupParametersTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.wereonlygroundvisibleatsequencestartLabel = uilabel(app.SetupParametersTab);
            app.wereonlygroundvisibleatsequencestartLabel.Text = 'were only ground visible at sequence start';
            app.Label = uilabel(app.SetupParametersTab);
            app.Label.Text = 'The Value above is little less than number of images';
            app.NbrofimagesforcheckEditFieldLabel = uilabel(app.SetupParametersTab);
            app.NbrofimagesforcheckEditFieldLabel.Text = 'Nbr.of images for check';
            app.NbrofimagesforcheckEditField = uieditfield(app.SetupParametersTab, 'numeric');
            app.NbrofimagesforcheckEditField.Limits = [1 Inf];
            app.NbrofimagesforcheckEditField.Value = 5;
        end
    end

    methods (Access = public)

        % Construct app
        function app = User_Interface()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DModelCreationbyDanielBriguetUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DModelCreationbyDanielBriguetUIFigure)
        end
    end
end